classdef DICE_v_1_1beta < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        StereonetAdjustsetsworkinprogressButton  matlab.ui.control.Button
        StereonetplotpolesButton        matlab.ui.control.Button
        Image                           matlab.ui.control.Image
        ImportDiscontinuitymeanorientButton  matlab.ui.control.Button
        InstructionsTextArea            matlab.ui.control.TextArea
        InstructionsTextAreaLabel       matlab.ui.control.Label
        ImportPointcloudButton          matlab.ui.control.Button
        ImportDiscontinuitysetsdataButton  matlab.ui.control.Button
        ImportDiscontinuity3DdataButton  matlab.ui.control.Button
        DiscontinuityIntensityCalculatorandEstimatorDICELabel  matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        P10ScanlineTab                  matlab.ui.container.Tab
        P10TooldescriptionTextArea      matlab.ui.control.TextArea
        P10TooldescriptionTextAreaLabel  matlab.ui.control.Label
        ExportDiscontinuityInformationButton  matlab.ui.control.Button
        CalculateDiscontinuityinformationButton  matlab.ui.control.Button
        LoadScanlineButton              matlab.ui.control.Button
        P10ImagelogTab                  matlab.ui.container.Tab
        CalculateP10Button              matlab.ui.control.Button
        P10calculationdepthrangeSpinner  matlab.ui.control.Spinner
        P10calculationdepthrangeLabel   matlab.ui.control.Label
        WellboredeviationAzimuthSpinner  matlab.ui.control.Spinner
        WellboredeviationAzimuthLabel   matlab.ui.control.Label
        WellboredeviationSpinner        matlab.ui.control.Spinner
        WellboredeviationLabel          matlab.ui.control.Label
        ImportmeansetorientationButton  matlab.ui.control.Button
        ImportpicksandsetButton         matlab.ui.control.Button
        P21CircularscanwindowTab        matlab.ui.container.Tab
        ExportdiscintersectionsCheckBox  matlab.ui.control.CheckBox
        P21andNCIpointcloudButton       matlab.ui.control.Button
        SetSpinner                      matlab.ui.control.Spinner
        SetSpinnerLabel                 matlab.ui.control.Label
        AnalyzespecificsetCheckBox      matlab.ui.control.CheckBox
        AutomaticsamplingLabel          matlab.ui.control.Label
        ManualsamplingLabel             matlab.ui.control.Label
        P21pointcloudButton             matlab.ui.control.Button
        P21TooldescriptionTextArea      matlab.ui.control.TextArea
        P21TooldescriptionTextAreaLabel  matlab.ui.control.Label
        ExportparametersButton          matlab.ui.control.Button
        DefineCWradiusbelowLabel        matlab.ui.control.Label
        CWradiusSpinner                 matlab.ui.control.Spinner
        CWradiusSpinnerLabel            matlab.ui.control.Label
        SelectthecentrerofCWButton      matlab.ui.control.Button
        P32ScanvolumeTab                matlab.ui.container.Tab
        ExportDiscInteresectionsCheckBox  matlab.ui.control.CheckBox
        CalculateP32and3DNCIButton      matlab.ui.control.Button
        SetSpinner_2                    matlab.ui.control.Spinner
        SetSpinner_2Label               matlab.ui.control.Label
        AnalyzespecifcsetCheckBox_2     matlab.ui.control.CheckBox
        scanradiusSpinner               matlab.ui.control.Spinner
        scanradiusSpinnerLabel          matlab.ui.control.Label
        P32TooldescriptionTextArea      matlab.ui.control.TextArea
        P32TooldescriptionTextAreaLabel  matlab.ui.control.Label
        DefinetherangeofthescanradiusthatyouwanttotestLabel_2  matlab.ui.control.Label
        DefinethevalueofthescanradiusthatyouwanttouseLabel  matlab.ui.control.Label
        CalculateP32Button              matlab.ui.control.Button
    end

    
    properties (Access = public)
        %Below the parameters used by all tools
        filename
        pathname
        Fracdata
        n
        filenameset
        pathnameset
        Fracset
        dipdir
        dip
        xyz
        Nxyz
        radius
        Set
        nplane
        filenamePC
        pathnamePC
        PC
        nPC
        mofilename%name of the file containing the mean orientation of the discontinuity sets
        Setmean
        meanNset
        %Below the parameters used by the P10
        pathnameSL
        S
        nSL
        listing
        rowname
        SLtable
        varname
        SLT
        tablefilenameXLSX
        %Below the parameters use by P21 tool
        CWr
        CWradius
        CWplane
        CWpoints
        maxCW_dist
        minCW_dist
        CWradius_range
        CWxyz_range
        CWxyz0
        CWNxyz
        mu_set
        mu
        I
        rho
        P21
        MTL2D
        stdevL2D
        max_L_uncut
        min_L_uncut
        max_l_cut
        min_l_cut
        export_AllIntersection
        %Below the parameters used by P32 tool
        p32scanradius
        
        SpecificAnalysisP21
        AnalyzedSetP21
        SpecificAnalysisP32
        AnalyzedSetP32

        %Below the parameters used by P10 Image Logs
        Pickdata
        Pickset
        step
        buffer
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ImportDiscontinuity3DdataButton
        function ImportDiscontinuity3DdataButtonPushed(app, event)
            [app.filename, app.pathname] = uigetfile({'*.xlsx', 'Select a XLSX file'},'mytitle',app.pathname);
            app.Fracdata=readtable(fullfile(app.pathname, app.filename));
            app.n=numel(app.Fracdata.Dip); %number of rows (= number of plane)
            %% DEFINE VARIABLES FOR CALCULATION
            app.dipdir =  app.Fracdata.DipDirection(:);%Dip direction
            app.dip =  app.Fracdata.Dip(:);%Dip angle
            app.xyz = [app.Fracdata.Xcenter(:), app.Fracdata.Ycenter(:), app.Fracdata.Zcenter(:)];
            app.Nxyz = [app.Fracdata.Nx(:),app.Fracdata.Ny(:),app.Fracdata.Nz(:)];
            app.radius = app.Fracdata.Radius(:);
            for i=1:app.nplane
                if app.Nxyz(i,3)<0 %if normal is oriented downward
                    app.Nxyz(i,1)=-app.Nxyz(i,1);
                    app.Nxyz(i,2)=-app.Nxyz(i,2);
                    app.Nxyz(i,3)=-app.Nxyz(i,3);
                else %if normal is oriented upward
                    app.Nxyz(i,1)=app.Nxyz(i,1);
                    app.Nxyz(i,2)=app.Nxyz(i,2);
                    app.Nxyz(i,3)=app.Nxyz(i,3);
                end
            end
        end

        % Button pushed function: ImportDiscontinuitysetsdataButton
        function ImportDiscontinuitysetsdataButtonPushed(app, event)
            [app.filenameset, app.pathnameset] = uigetfile({'*.xlsx', 'Select a XLSX file'},'mytitle',app.pathname);
            app.Fracset=readtable(fullfile(app.pathnameset, app.filenameset));
            app.n=numel(app.Fracset.Set); %number of rows (= number of plane)
            
            %% DEFINE VARIABLES FOR CALCULATION
            app.Set = app.Fracset.Set;
            app.Set(isnan(app.Set)) = max(app.Set) + 1;%Change the NaN value (used for the random/not defined set) with a new numbering
            app.nplane=app.n;
            
        end

        % Button pushed function: LoadScanlineButton
        function LoadScanlineButtonPushed(app, event)
            % Define folder in which dxf scanlines files are stored
            % % % [filenameSL, pathnameSL]=uigetfile({'*.dxf', 'Select a DXF file'}, 'Select a DXF file',...
            % % %     'F:\Menegoni\Antola\Outcrop_models\St_280116\Stazione 1bis_new\DXF\Scanlines_DXF');% <- MODIFY the PATH
            app.pathnameSL=uigetdir(app.pathname, 'Select folder in which scanline.dxf files are stored');
            app.listing = dir(app.pathnameSL);
            
            
            app.rowname = cell(sum(~cellfun(@isempty,{app.listing(3:end).name})),1);
            for i=1:sum(~cellfun(@isempty,{app.listing(3:end).name}))
                app.rowname(i,1)={app.listing(i+2,1).name};
            end
            app.rowname(find(contains(app.rowname,'.DS_Store')))= [];
            app.SLtable=zeros(length(app.rowname),5 + 3*(max(app.Set)));
            app.nSL=sum(~cellfun(@isempty,{app.listing(3:end).name}));%number of scanline detected
        end

        % Button pushed function: CalculateDiscontinuityinformationButton
        function CalculateDiscontinuityinformationButtonPushed(app, event)
            %% Cylce for several scanlines
            app.S=cell(max(app.Set),app.nSL);
            for loop =  1:app.nSL%-1 %check please, if it is correct to remove -1
                %% Read scanline DXF file
                clear filenameSL fId c_ValAsoc m_GrCode m_PosCero indInSecEnt m_indFinSecEnt ...
                    c_Poi iPoi iEnt m_GrCodeEnt c_ValAsocEnt nomEnt Pscanline1 Pscanline2 ...
                    Cscanline Lscanline Dplane IntPTuncut PLANE LINE IntPT_uncut ...
                    D_Pscan1_IntPT D_Pscan2_IntPT D_IntPT_centerPlane UnOrd_distance ...
                    theta v points X Y Z selectIntPT_cut selectUnOrd_dist pointIntersecting ...
                    UnOrd_dist npointIntersecting xyz_selection Nxyz_selection Set_unord ...
                    IntPT_cut Ord_dist xyz_unord Nxyz_unord AllValue IntPT_cut_ord Ord_dist_ord ...
                    xyz_ord Nxyz_ord Set_ord AllOrd Pointsforcalc D_RQD  RQD xyz_ordtemp ...
                    Nxyz_ordtemp Spacing_temp P_temp P_temp Spacing_temp Set_Smean Set_mode ...
                    Set_median SL_sets P10 %clear variables
                
                %% ReDEFINE VARIABLES FOR CALCULATION
                %%
                % number of plane to plot in the stereogram
                filenameSL=app.rowname{loop};
                fId = fopen(fullfile(app.pathnameSL,filenameSL));
                c_ValAsoc = textscan(fId,'%d%s','Delimiter','\n');
                fclose(fId);
                % Code Group Matrix
                m_GrCode = c_ValAsoc{1};
                % Associated value String Cell
                c_ValAsoc = c_ValAsoc{2};
                %[m_GrCode,c_ValAsoc] = c_ValAsoc{:};
                m_PosCero = find(m_GrCode==0);
                %Is searched by (0,SECTION),(2,ENTITIES)
                indInSecEnt = strmatch('ENTITIES',c_ValAsoc(m_PosCero(1:end-1)+1),'exact');
                %(0,ENDSEC)
                m_indFinSecEnt = strmatch('ENDSEC',c_ValAsoc(m_PosCero(indInSecEnt:end)),'exact');
                % Entities Position
                m_PosCero = m_PosCero(indInSecEnt:indInSecEnt-1+m_indFinSecEnt(1));
                % Variable initiation
                %accelerate?
                c_Poi = cell(sum(strcmp('POINT',c_ValAsoc(m_PosCero))),2);
                c_Poi = cell(1,2);
                %
                iPoi = 1;
                % Loop on the Entities
                for iEnt = 1:length(m_PosCero)-2
                    m_GrCodeEnt = m_GrCode(m_PosCero(iEnt+1):m_PosCero(iEnt+2)-1);
                    c_ValAsocEnt = c_ValAsoc(m_PosCero(iEnt+1):m_PosCero(iEnt+2)-1);
                    nomEnt = c_ValAsocEnt{1};  %c_ValAsocEnt{m_PosCero(iEnt+1)}
                    %In the entitie's name is assumed uppercase
                    switch nomEnt
                        case 'VERTEX'
                            % (X,Y,Z) Position
                            c_Poi{iPoi,1} = [str2double(f_ValGrCode(10,m_GrCodeEnt,c_ValAsocEnt)),...
                                str2double(f_ValGrCode(20,m_GrCodeEnt,c_ValAsocEnt)),...
                                str2double(f_ValGrCode(30,m_GrCodeEnt,c_ValAsocEnt))];
                            % Layer
                            c_Poi(iPoi,2) = f_ValGrCode(8,m_GrCodeEnt,c_ValAsocEnt);
                            % Add properties
                            %
                            iPoi = iPoi+1;
                            %case Add Entities
                            
                            % (X,Y,Z) vertexs
                            
                            
                    end
                end
                Pscanline1=c_Poi{1,1};
                Pscanline2=c_Poi{2,1};
                Cscanline=mean(iPoi);%center of the scanline
                Lscanline=   sqrt((Pscanline1(1)-Pscanline2(1))^2 ...
                    + (Pscanline1(2)-Pscanline2(2))^2 ...
                    + (Pscanline1(3)-Pscanline2(3))^2);%% length of the scanline
                %% CALCULATION INTERSECTION FRACTURES PLANES-SCANLINE
                
                IntPTuncut=zeros(app.nplane,3);
                %1) Calculate intersection between all planes and scanline------
                figure(4)
                hold on; grid on; box on % hold on, box on and grid are useful for plotting
                for i=1:app.nplane
                    
                    PLANE = createPlane(app.xyz(i,:), app.Nxyz(i,:));%Set plane properly for 'intersectLinePlane' and 'distancePoints3D' formulas
                    LINE=createLine3d(Pscanline1, Pscanline2);%Set line properly for 'intersectLinePlane' and 'distancePoints3D' formulas
                    IntPT_uncut (i,:) = intersectLinePlane(LINE, PLANE);% Gives coordinate of points of intersection
                    %The previous formula calculate intersection points between plane and
                    %line. Dimension of line is unlimited.
                    %Next 8 strings need to select only the intersection the lye on the
                    %scanline (on the limited segment).
                    D_Pscan1_IntPT(i) = distancePoints3d(Pscanline1, IntPT_uncut(i,:));
                    D_Pscan2_IntPT(i) = distancePoints3d(Pscanline2, IntPT_uncut(i,:));
                    D_IntPT_centerPlane(i) = distancePoints3d(IntPT_uncut(i,:), app.xyz(i,:));
                    UnOrd_distance(i,1)=D_Pscan1_IntPT(i);
                    if D_Pscan1_IntPT(i)<Lscanline && D_Pscan2_IntPT(i)<Lscanline && D_IntPT_centerPlane(i)< app.radius(i)
                        IntPT_uncut(i,:)= IntPT_uncut (i,:);
                        UnOrd_distance(i,1)=UnOrd_distance(i,1);
                        %plot fracture discs that intersect the scanline
                        theta=0:0.1:2*pi; %Setting the spacing in which coordinate of the discontinuity disc are calculated
                        v=null(app.Nxyz(i,:));% calculate vectors needed to plot the discs
                        points=repmat(app.xyz(i,:)',1,size(theta,2))+app.radius(i)*(v(:,1)*cos(theta)+v(:,2)*sin(theta));%calculate points coordinate of the discs
                        fill3(points(1,:),points(2,:),points(3,:),'b', 'FaceAlpha', 0.5);%Plot 3D disc for each plane
                        xlabel('x-axis (East)'); ylabel('y-axis (Nord)'); zlabel('z-axis (Elev)')%Set the label of the axes of the 3D plot
                    else
                        IntPT_uncut(i,:)=NaN;%All points that don't belong to the scanline are unselected (defining NaN as coordinate value)
                        UnOrd_distance(i,1)=NaN;
                    end
                    
                end %calulate only intersection without considering order


                %2) It is created a new matrix which contain only non-NaN value (only
                %points coordinate that lies on the scanline)
                selectIntPT= ~isnan( IntPT_uncut(:,1));%find the index of the non-NaN values used for the further selection of the values
                IntPT_cut=IntPT_uncut(selectIntPT,:);%select only the non-NaN values
                UnOrd_dist=UnOrd_distance(selectIntPT,:);%select only the non-NaN values
                xyz_unord = app.xyz (selectIntPT,:);%select only the non-NaN values
                Nxyz_unord = app.Nxyz(selectIntPT,:);%select only the non-NaN values
                Set_unord = app.Set(selectIntPT,:);
                npointIntersecting= (numel(IntPT_cut))/3;%count number of intersection points
                
                
                %Give order in point distribuition
                
                AllValue = [UnOrd_dist,IntPT_cut,xyz_unord,Nxyz_unord,Set_unord];% Matrix with all parameters to be ordered
                AllOrd=sortrows(AllValue);% Ordering the matrix with all parameters
                % and then create new variables for distance (dist), points of
                % intersection(Int_PT, discontinuity center (xyz),
                % disconinuity normals (Nxyz) and discontinuity sets (Set)
                Ord_dist_ord = AllOrd(:,1);
                IntPT_cut_ord = AllOrd(:,2:4);
                xyz_ord = AllOrd(:,5:7);
                Nxyz_ord = AllOrd(:,8:10);
                Set_ord = AllOrd(:,11);
                
                %creare una matrice dove sono storaggiati tutti i punti ordinati e il cui
                %primo punto è il Pscanline1 e l'ultimo il P-scanline2
                Pointsforcalc=[Pscanline1;IntPT_cut_ord;Pscanline2];
                
                % calculation of distances bewteen points for RQD estimation
                
                for i=1:npointIntersecting+1
                    D_RQD(i)=dist2points(Pointsforcalc(i,:), Pointsforcalc(i+1,:));
                end
                
                %estimation of RQD
                RQD=(sum(D_RQD(D_RQD>0.10)) / Lscanline)*100; %estimation of the RQD as for the core
                
                %Plot Scanline_Plane_and Intersection point
                %Plot Scanline
                figure(2)
                line(   [Pscanline1(1) Pscanline2(1)]', ...
                    [Pscanline1(2) Pscanline2(2)]', ...
                    [Pscanline1(3) Pscanline2(3)]', 'Color', 'r', 'LineWidth',4);
                hold on
                if length(app.PC(1,:))>3%Plot poincloud with color if have more than 3 columnb (x,y,z)
                    pcshow(app.PC(:,1:3),app.PC(:,4:6)/255);
                else
                    pcshow(app.PC(:,1:3))%else if there is no color info, plot it withoutu them
                end
                
                figure(3)
                line(   [Pscanline1(1) Pscanline2(1)]', ...
                    [Pscanline1(2) Pscanline2(2)]', ...
                    [Pscanline1(3) Pscanline2(3)]', 'Color', 'r', 'LineWidth',4);
                hold on
                for i=1:numel(Pointsforcalc)/3
                    scatter3(Pointsforcalc(i,1),Pointsforcalc(i,2),Pointsforcalc(i,3),'filled')
                end
                
                %5) Plot the point of intersection between fracturs and scanline the discontinuities
                figure(2)
                for i=1:numel(Pointsforcalc)/3
                    scatter3(Pointsforcalc(i,1),Pointsforcalc(i,2),Pointsforcalc(i,3),'filled')
                    hold on
                end
                
                %% calculation set statistic
                
                %% calculation spacing
                %it calculates the sapcing considering the mean attitude of
                %the set defined in the Set mean orient input data
                Set_Smean=zeros(1,max(app.Set));
                if npointIntersecting>1%there are more than two fractures that interesct the scanlines
                    for jj=min(app.Setmean.Set) : (max(app.Setmean.Set))%calculate spacing (and number of intersectio per sets, for all the sets except the Random set (=max(app.Set))
                        if sum(Set_ord == jj)>1
                            Spacing_temp=zeros(1,sum(Set_ord == jj)-1);
                            IntPT_cut_ordtemp=zeros(sum(Set_ord == jj),3);
                            IntPT_cut_ordtemp(:,1:3)=IntPT_cut_ord(Set_ord==jj,:);
                            for ii=1:(numel(IntPT_cut_ordtemp) / 3)-1
                                Spacing_temp(ii)=abs(plane2point(app.meanNset(app.Setmean.Set==jj,:),IntPT_cut_ordtemp(ii,:),IntPT_cut_ordtemp(ii+1,:)));
                            end
                            app.S{jj,loop}=Spacing_temp;
                            Set_Smean(1,jj)=mean(Spacing_temp);
                            Set_median(1,jj)=median(Spacing_temp);
                            SL_sets(1,jj)=sum(Set_ord==jj);%number of fractures belonging to set(j) that intersect the scanline
                            
                            
                        elseif sum(Set_ord == jj)==1
                            Set_Smean(1,jj)=0;
                            Set_median(1,jj)=0;
                            SL_sets(1,jj)=sum(Set_ord==jj);
                            
                        else
                            Set_Smean(1,jj)=0;
                            Set_median(1,jj)=0;
                            SL_sets(1,jj)=0;
                        end
                    end
                    
                    if max(app.Set)>max(app.Setmean.Set)%if max(app.Set)>max(app.Setmean.Set) there is a random set
                        SL_sets(1,max(app.Set))=sum(Set_ord==(max(app.Set)));%calculate the number of random fractures intersected
                        if sum(Set_ord == max(app.Set))>1
                            IntPT_cut_ordtemp=zeros(sum(Set_ord == jj),3);%initialize the array in which the intersection points of the random fractures will be stored
                            IntPT_cut_ord(Set_ord==max(app.Set),:);%store the intersection points of the random fractures
                            for ii=1:(numel(IntPT_cut_ordtemp(:,1))-1)
                                Spacing_temp(ii)=abs(dist2points(IntPT_cut_ordtemp(ii,:),IntPT_cut_ordtemp(ii+1,:)));%for the random fractures, the sapcing is calculated as the distance betweem two consequent fracture-scanlines interesction (along the scanline direction)
                            end
                            app.S{max(app.Set),loop}=Spacing_temp;
                            Set_Smean(1,max(app.Set))=mean(Spacing_temp);
                            Set_median(1,max(app.Set))=median(Spacing_temp);
                        elseif sum(Set_ord == max(app.Set))==1
                            Set_Smean(1,max(app.Set))=0;
                            Set_median(1,max(app.Set))=0;
                        else
                            Set_Smean(1,max(app.Set))=0;
                            Set_median(1,max(app.Set))=0;
                        end
                    end
                    
                    %% Calculate statistic
                    P10=npointIntersecting/Lscanline;%fracture intesity P01 (N#fractures/scanlineLength)
                    app.SLtable(loop,1)=Lscanline;
                    app.SLtable(loop,2)=npointIntersecting;%number of fracture that intersect the scanline
                    app.SLtable(loop,3)=P10;%P10 value (all the fracture toghether)
                    app.SLtable(loop,4)=numel(unique(Set_ord));%number of fracture sets encountered by the scanline
                    for jj=1:max(app.Set)%for each fracture set defines the parameters below
                        app.SLtable(loop,4+jj)=SL_sets(jj);%number of fracture of the set interescted by the scanline
                        app.SLtable(loop,4+max(app.Set)+jj)=Set_Smean(jj);%mean spacing value of the fracture set
                        app.SLtable(loop,4+2*max(app.Set)+jj)=Set_median(jj);%median spacing value of the fracture set
                    end
                    
                    
                elseif npointIntersecting==1%if only one fracture is intersected by the scaline
                    for jj=min(app.Set):max(app.Set)
                        if Set_ord==jj
                            SL_sets(1,jj)=1;
                            Set_Smean(1,jj)=0;
                            Set_median(1,jj)=0;
                        else
                            SL_sets(1,jj)=0;
                            Set_Smean(1,jj)=0;
                            Set_median(1,jj)=0;
                        end
                    end
                    
                    P10=npointIntersecting/Lscanline;% fracture intesity P01 (N#fractures/scanlineLength)
                    app.SLtable(loop,1)=Lscanline;
                    app.SLtable(loop,2)=npointIntersecting;%number of fracture that intersect the scanline
                    app.SLtable(loop,3)=P10;%P10 value (all the fracture toghether)
                    app.SLtable(loop,4)=numel(unique(Set_ord));%as the defintions above
                    for jj=1:max(app.Set)%for each fracture set defines the parameters below
                        app.SLtable(loop,4+jj)=SL_sets(jj);%number of fracture of the set interescted by the scanline
                        app.SLtable(loop,4+max(app.Set)+jj)=Set_Smean(jj);%mean spacing value of the fracture set
                        app.SLtable(loop,4+2*max(app.Set)+jj)=Set_median(jj);%median spacing value of the fracture set
                    end
                end
                
                app.SLtable(loop,end)=RQD;%Rock Quality Designation (RQD) index
            end
            for ii=min(app.Set):max(app.Set)
                tempS=zeros(1,length(cat(2,app.S{ii,:})));
                tempS=cat(2,app.S{ii,:});
                tempS(tempS==0)=[];
                figure()
                histogram(tempS,20)
                xlabel('Spacing (m)')
                ylabel('frequency')
                title(['Set ', num2str(ii),', N = ',num2str(length(tempS))])
            end
            disp('End of calculation: please export the data')
            
        end

        % Button pushed function: ImportPointcloudButton
        function ImportPointcloudButtonPushed(app, event)
            [app.filenamePC, app.pathnamePC] = uigetfile({'*.txt', 'Select a txt file'},'mytitle',...
                app.pathname);%
            app.PC=importdata(fullfile(app.pathnamePC, app.filenamePC));
            app.nPC=length(app.PC(:,1));
        end

        % Button pushed function: ExportDiscontinuityInformationButton
        function ExportDiscontinuityInformationButtonPushed(app, event)
            %create table and export XLSX
            app.varname= cell(1,numel(app.SLtable(1,:)));
            
            app.varname(1,1)={'SL_length_m'};
            app.varname(1,2)={'N_Fractures_tot'};
            app.varname(1,3)={'N_P10'};
            app.varname(1,4)={'N_Sets_intersected'};
            for i=1:max(app.Set)
                app.varname(1,4+i)={['N_fractures_set',num2str(i)]};
                app.varname(1,4+max(app.Set)+i)={['Smean_set',num2str(i)']};
                app.varname(1,4+2*max(app.Set)+i)={['Smedian_set',num2str(i)']};
            end
            app.varname(1,end)={'RQD'};


            app.SLT=array2table(app.SLtable);
            app.SLT.Properties.RowNames=app.rowname;
            app.SLT.Properties.VariableNames=app.varname;
            app.tablefilenameXLSX='Results.xlsx';
            writetable(app.SLT,fullfile(app.pathnameSL,app.tablefilenameXLSX),'WriteRowNames',true);
            
            clear fid
            fid = fopen(fullfile(app.pathnameSL,'Spacing_values.txt'),'wt');
            for ii =min(app.Set):max(app.Set)
                fprintf(fid, ['SET', num2str(ii),'\t']);
                fprintf(fid, [num2str(cat(2,app.S{ii,:})), '\n']);
            end
            fclose(fid);
            
            disp('End of export process')
        end

        % Value changed function: CWradiusSpinner
        function CWradiusSpinnerValueChanged(app, event)
            app.CWr = app.CWradiusSpinner.Value;
        end

        % Button pushed function: SelectthecentrerofCWButton
        function SelectthecentrerofCWButtonPushed(app, event)
            app.CWradius_range = app.CWr/10;
            [app.CWplane, app.maxCW_dist, app.minCW_dist] = selectCW(app.PC,app.CWr); %select the circular window
            app.CWxyz0=app.CWplane(4:6);
            app.CWNxyz=app.CWplane(7:9); %define its center and normal vector
        end

        % Callback function
        function CWsamplingButtonPushed(app, event)
            app.CWxyz_range= CWxyzRange(app.CWxyz0, app.CWNxyz, app.CWradius_range, app.minCW_dist, app.maxCW_dist);%create sevral CWs to sample all the 3D space
        end

        % Button pushed function: ExportparametersButton
        function ExportparametersButtonPushed(app, event)
            figure()
            thetaCW=0:0.1:2*pi; vCW=null(app.CWNxyz);
            pointsCW0=repmat(app.CWxyz0',1,size(thetaCW,2))+app.CWr*(vCW(:,1)*cos(thetaCW)+...
                vCW(:,2)*sin(thetaCW));
            
            fill3(pointsCW0(1,:),pointsCW0(2,:),pointsCW0(3,:),'r', 'FaceAlpha', 1);%plot disc of circular window
            xlabel('x-axis (East)');ylabel('y-axis (Nord)');zlabel('z-axis (Elev)')
            hold on; grid on; axis on; axis equal
            for i = 1 : app.nplane
                theta=0:0.1:2*pi; %Setting the spacing in which coordinate of the discontinuity disc are calculated
                v=null(app.Nxyz(i,:));% calculate vectors needed to plot the discs
                points=repmat(app.xyz(i,:)',1,size(theta,2))+app.radius(i)*(v(:,1)*cos(theta)+v(:,2)*sin(theta));%calculate points coordinate of the discs
                
                % Change name to the coordinate in a better and more resonable name
                X=points(1,:)';
                Y=points(2,:)';
                Z=points(3,:)';
                fill3(points(1,:),points(2,:),points(3,:),'b', 'FaceAlpha', 0.5);%Plot 3D disc for each plane
            end
            f1=figure;
            
            CWxyz = app.CWxyz0;
            thetaCW=0:0.1:2*pi; vCW=null(app.CWNxyz);
            pointsCW=repmat(CWxyz',1,size(thetaCW,2))+app.CWr*(vCW(:,1)*cos(thetaCW)+...
                vCW(:,2)*sin(thetaCW));
            XCW=pointsCW(1,:)';YCW=pointsCW(2,:)';ZCW=pointsCW(3,:)';
            hold on;
            fill3(pointsCW(1,:),pointsCW(2,:),pointsCW(3,:),'b', 'FaceAlpha', 0.5);%plot disc of circular window
            xlabel('x-axis (East)');ylabel('y-axis (Nord)');zlabel('z-axis (Elev)')
            hold on; grid on; axis on; axis equal
            
            if app.CWplane(2) < 180
                azView = app.CWplane(2)-180;
            elseif app.CWplane(2) > 180
                azView = 360-app.CWplane(2)+180;
            elseif app.CWplane(2) == 180
                azView =0;
            elseif app.CWplane(2) == 0 || app.CWplane(2) == 360
                azView = 180;
            end
            elView=90-app.CWplane(1);
            view(azView,elView)
            
            
            [L(:,1),l(:,1),m_mauldon(:,1),n_mauldon(:,1),countCWDisc_inter] =intCWdisc(CWxyz, app.CWNxyz, app.CWr, app.xyz, app.Nxyz, app.radius, app.Set, f1);


            [app.mu_set,app.mu,app.I,app.rho,app.P21,app.MTL2D,app.stdevL2D,app.max_L_uncut,app.min_L_uncut,app.max_l_cut,app.min_l_cut] = CWstatistic(l,L,app.Set,m_mauldon,n_mauldon,app.CWr,countCWDisc_inter);
            disp('Normal statistics')
            disp(['CWr P21 MTL2D stdL2D maxLuncut minLuncut maxLcut minLcut I rho mu muset'])
            disp(num2str([app.P21,app.MTL2D,app.stdevL2D,app.max_L_uncut,app.min_L_uncut,app.max_l_cut,app.min_l_cut,app.I,app.rho,app.mu,app.mu_set]))
            
        end

        % Callback function
        function ScanradiusEditFieldValueChanged(app, event)
            app.p32scanradius = app.ScanradiusEditField.Value;%defines the min value of the p32 scan radius range
        end

        % Button pushed function: CalculateP32Button
        function CalculateP32ButtonPushed(app, event)
            disp('Welcome from the P32 calculator')
            tic
            %Load all the app object to get aviable the parfor
            if app.SpecificAnalysisP32 == 0
                xyz=app.xyz;
                Nxyz=app.Nxyz;
                radius=app.radius;
                nplane=app.n;
            else
                xyz=app.xyz(app.Set==app.AnalyzedSetP32,:);
                Nxyz=app.Nxyz(app.Set==app.AnalyzedSetP32,:);
                radius=app.radius(app.Set==app.AnalyzedSetP32,:);
                nplane=sum(app.Set==app.AnalyzedSetP32);
            end
            pn=app.pathname;
            PC=app.PC;
            nPC=app.nPC;
            SVr=app.p32scanradius;
            %% LOAD & READ the Pointcloud file
            usePC=1; %if UsePC=1 the code use the pointcloud, if = 0 it doesn't use the PC
            if usePC==0
                SVxyz=[1.295830,1.103351,1.608449];% define scan volume (sphere) center
                PC=SVxyz;
            end
            %%P32 calculation
            %1) calculate intersection between the scan volume and the fracture
            %'infinite plane'
            sumarea=0;
            P32=zeros(length(PC),1);
            mkdir (pn, 'exported_pointclouds_parfor')
            disp('DATASET_STATISTIC')
            disp(['Pointcloud_= ', num2str(length(PC(:,1))),' (points)'])
            disp(['Fracture_numbers_= ', num2str(nplane)])
            % CALCULATE P32
            disp(['START_calculation_P32_for_scan radius_= ',num2str(SVr,'%.2f'),' (m)'])
            %             D = parallel.pool.DataQueue;%Set a DataQueue for the watbar
            %             h = waitbar(0, 'Please wait ...');%Create the wait bar
            %             afterEach(D, @nUpdateWaitbar);%define the updating parameter
            %
            %             N = length(PC(:,1));
            %             p = 1;
            %
            
            parfor jloop = 1: length(PC(:,1))
                %disp(['analyzed point = ', num2str(jloop)])
                SVxyz= PC(jloop,1:3);
                SV=[SVxyz,SVr];% scan volume express as required yby the function intersectPlaneSphere
                sumarea=0;
                
                for iloop = 1 : nplane
                    %disp(['analyzed disc = ', num2str(iloop)])
                    
                    scanCIRCtemp =zeros(1,4);
                    PLANEtemp = [xyz(iloop,:), Nxyz(iloop,:)];
                    %dPlCi = bsxfun(@minus, xyz(i), SVxyz); %distance between the centers of the plane and the sphere
                    %dPlCi = sqrt(d(1)^2 + d(2)^2 + d(3)^2);
                    dOrSp= (-dot (Nxyz(iloop,:), PC(jloop,1:3))); %distance from origin-sphere centertoward normal vector of the plane
                    dOrPl= (-dot (Nxyz(iloop,:), xyz(iloop,:))); %distance from origin-plane centertoward normal vector of the plane
                    aSpPl=abs(dOrSp - dOrPl); %distance from sphere-plane centers toward normal vector of the plane


                    if aSpPl < SVr % && dPlCi<(SVr+radius(i))
                        scanCIRCtemp = intPlaneSphere(PLANEtemp, SV);
                        IntArea = intCirc2Circ(scanCIRCtemp(1:3),scanCIRCtemp(4), xyz(iloop,:), radius(iloop));
                    else
                        IntArea = 0;
                    end


                    sumarea = sumarea + IntArea;
                end
                
                P32 (jloop,1) = sumarea / ((4/3)*(3.14159265359)*(SVr^3)); %total P32 (all sets together)
                %                 send(D, jloop);
            end
            disp(['->Exporting_results_at_= ',num2str(toc/60,'%.1f'), '(min)'])
            clear fid
            if app.SpecificAnalysisP32 == 0
                fid = fopen(fullfile([pn,'exported_pointclouds_parfor'],['P32_r_',num2str(SVr,'%.2f'),'.txt']),'wt');
            else
                fid = fopen(fullfile([pn,'exported_pointclouds_parfor'],['Set_',num2str(app.AnalyzedSetP32),'_P32_r_',num2str(SVr,'%.2f'),'.txt']),'wt');
            end
            if numel(PC(1,:))>3
            fprintf(fid, 'X Y Z R G B P32 \n');
            fprintf(fid, '%.6f %.6f %.6f %3.0f %3.0f %3.0f %.3f \n', [PC(:,1),PC(:,2),PC(:,3),PC(:,4),PC(:,5),PC(:,6),P32(:)]');
            else
            fprintf(fid, 'X Y Z P32 \n');
            fprintf(fid, '%.6f %.6f %.6f %.3f \n', [PC(:,1),PC(:,2),PC(:,3),P32(:)]');
            end
            fclose(fid);
            disp('End of point cloud exporting process')
            %             function nUpdateWaitbar(~)
            %                 waitbar(p/N, h);
            %                 p = p + 1;
            %             end
        end

        % Value changed function: scanradiusSpinner
        function scanradiusSpinnerValueChanged(app, event)
            app.p32scanradius= app.scanradiusSpinner.Value;
        end

        % Button pushed function: P21pointcloudButton
        function P21pointcloudButtonPushed(app, event)
            disp('Welcome to the P21 automatic calculator')
            disp('It can take a while for the calculation')
            disp('depending by the pointcloud size and the fracture dataset.')
            disp('If it takes too much time, consider to stop the calculation')
            disp('and use a lower resolution point cloud.')


            % Load in the local memory some variable to perform the parfor
            % loop
            
            PC=app.PC;
            nPC=app.nPC;
            ptCloud=pointCloud(PC(:,1:3));
            CWr=app.CWr;
            if app.SpecificAnalysisP21 == 0
                xyz=app.xyz;
                Nxyz=app.Nxyz;
                radius=app.radius;
                nplane=length(radius);
            elseif app.SpecificAnalysisP21 == 1
                xyz=app.xyz(app.Set(:)==app.AnalyzedSetP21,:);
                Nxyz=app.Nxyz(app.Set(:)==app.AnalyzedSetP21,:);
                radius=app.radius(app.Set(:)==app.AnalyzedSetP21,:);
                nplane=length(radius);
            end
            
            tic
            reqKnn=requiredKNNcalc(PC(:,1:3),ptCloud,CWr);
            disp(['Time to calculate the best Knn points number ', num2str(toc/60), ' min.'])
            tic

            %Initializing output variables
            P21=zeros(length(app.PC(:,1)),1);% record the p21
            MTLcut=zeros(length(app.PC(:,1)),4);% record the statistic of the trace length only inside the circularwindow(mean, st.dev., min, max)
            MTLuncut=zeros(length(app.PC(:,1)),4);% record the statistic of the trace length of the fracture that intersect the window (mean, st.dev., min, max)
            num_idxK=zeros(length(app.PC(:,1)),1);%record the number of K nearest neighbour
            slopeDipdirDip=zeros(length(app.PC(:,1)),2);%record the local attitude of the slope calculated 
            SPr=zeros(length(app.PC(:,1)),1);%record the actual scan radius used by DICE (it can sligthly differ from the selected one)

            %Main calculation loop
            parfor ptloop= 1: nPC %define a loop foe every point of the cloud
                [idxK, ptdist] = findNearestNeighbors(ptCloud,PC(ptloop,1:3),4*reqKnn);%calculate the index of the Knn points.
                % the idxK indicates the position of the Knn points inside the PC matrix;
                % the ptdist indicates the distance of the points from the 'ptloop' one
                idxK(ptdist>CWr)=[];
                ptdist(ptdist>CWr)=[];
                SPpoints = PC(idxK,1:3);%define the points belonging to the scan area (based on Knn algorithm)
                num_idxK(ptloop)=length(idxK(:,1));
                %Calculating the center, normals, radius and attitude (dipdir and dip) of the scanplane
                SP_center = mean(PC(idxK,1:3));%scanplane center
                if length(idxK)>3 % if the analyzed point has three or more nearest neighbour points closer that fall in the scan radius, the code perfomr the consequent analysis
                    % This if loop is necessary becasue sometimes, sparse points could
                    % be present and they have a distance from the other point of the
                    % pointcloud longer than the defined scan radius. Therefore, it is
                    % necessary to define exclude these points from calculation
                    %scanplane normals
                    [V, D] = eig(cov(PC(idxK,1:3))); % calculate eigenvector 'V' and eigenvalue 'D' of thecovariance matrix 'C'
                    SP_N = V(:,1)';
                    SP_N = SP_N/norm(SP_N);%RECENTLY ADDED
                    SPr(ptloop,1) = max(ptdist);%radius equal to the most distance filtered Knn points (always lower than userSP)
                    [sdipdir,sdip]= normal2attitude(SP_N);%attitude of the scanplane
                    slopeDipdirDip(ptloop,:)=[sdipdir,sdip];
                    %         if SP_N (1,3)<0
                    %             overhanging(ptloop,1)=1;
                    %         else
                    %             overhanging(ptloop,1)=0;
                    %         end
                    %calculating intersection between discontinties and finite scan plane
                    [p21, muL2D, stdL2D, maxL2D,minL2D,muL3D, stdL3D, maxL3D,minL3D] = P21calc(SP_center, SP_N, SPr(ptloop,1), xyz, Nxyz, radius);
                    P21(ptloop)=p21;
                    MTLcut(ptloop,:)=[muL2D, stdL2D, maxL2D,minL2D];%mean, st.dev., max and min values of the 'cutted'trace length (only the segment within the circular window)
                    MTLuncut(ptloop,:)= [muL3D, stdL3D, maxL3D,minL3D];%mean, st.dev., max and min values of dimension of the fractures that intersect the circular window
                end
            end
            disp(['->Exporting_results_at_= ',num2str(toc/60,'%.1f'), '(min)'])
            mkdir (app.pathname,'P21pointclouds_resuls')
            clear fid
            fid = fopen(fullfile([app.pathname,'P21pointclouds_resuls'],['P21_r_',num2str(app.CWr,'%.1f'),'.txt']),'wt');
            fprintf(fid, 'X Y Z R G B P21 MTL2D TL2Dstd TL2Dmax TL2Dmin MFL3D FL3Dstd FL3Dmax FL3Dmin DipDir Dip Scanradius \n');
            fprintf(fid, '%.6f %.6f %.6f %3.0f %3.0f %3.0f %.3f %.3f %.3f %.3f %.3f %.3f %.3f %.3f %.3f %.3f %.3f %.3f \n', ...
                [PC(:,1),PC(:,2),PC(:,3),...
                PC(:,4),PC(:,5),PC(:,6),P21(:),...
                MTLcut(:,1),MTLcut(:,2),MTLcut(:,3),MTLcut(:,4),...
                MTLuncut(:,1),MTLuncut(:,2),MTLuncut(:,3),MTLuncut(:,4)...
                slopeDipdirDip(:,1),slopeDipdirDip(:,2), SPr(:)]');
            fclose(fid);
            disp('End of point cloud exporting process')
            
        end

        % Button pushed function: ImportDiscontinuitymeanorientButton
        function ImportDiscontinuitymeanorientButtonPushed(app, event)
            [app.mofilename, app.pathname] = uigetfile({'*.xlsx', 'Select a XLSX file'},'mytitle',app.pathname);
            app.Setmean=readtable(fullfile(app.pathname, app.mofilename));
            app.meanNset=attitude2normal(app.Setmean.DipDirection,app.Setmean.Dip,app.Setmean.Set);
        end

        % Button pushed function: StereonetplotpolesButton
        function StereonetplotpolesButtonPushed(app, event)
            figure();
            schmidt %function by Gerry Middleton, November 1995
            title ('Stereonet (Schmidt net)')
            hold on
            % Draws the oriented data into Schmidt's net (G. Middleton, November 1995)
            theta=(90-app.dipdir); % Poles are at the opposite direction to dip direction -> -180
            r=sqrt(2)*sind((90-app.dip-90)/2); % Poles are perpendicular to the dip
            % Coordinates on the strereographic projection
            for n=1:app.nplane;
                xp(n) = r(n)*cosd(theta(n));
                yp(n) = r(n)*sind(theta(n));
                plot(xp(n),yp(n),'*k')
            end
            % Clustering of the discontintuities---------------------------------------
            % Draws the automated clustering results (kmeans-method) on a Schmidt's equal area net
            Stereogram(app.dipdir, app.dip, app.nplane, app.Set, xp, yp)
            % Stereogram funtionc of G. Middleton (1995) modified from
            %M. Markovaara-Koivisto, Aalto University School of Science and
            % Technology, Finland (2012)
        end

        % Button pushed function: StereonetAdjustsetsworkinprogressButton
        function StereonetAdjustsetsworkinprogressButtonPushed(app, event)
            % Asks is the user is satisfied with the result of the clustering
            % If not, the user may change the clustering in an interactive window
            Ready = [];
            button=1;
            answer = input('Do you want to change the results of the automatic clustering (y/n):', 's');
            while (answer == 'y' & isempty(Ready) & button==1)
                disp('Left click with mouse on the symbols as many times as it takes to change the cluster into the wanted one.')
                disp('When you are finnished, right click with mouse on the figure')
                [xcho, ycho, button]=ginput(1);
                
                % Calculates the minimun distance between the indicated point and the data
                % points and searches the closes data point, gives index to the row and
                % changes the cluster group into the next one or to the first one.
                % Refreshes the figure.
                distance=sqrt((xp-xcho).^2+(yp-ycho).^2);
                cho=find(distance==min(distance))
                % Observation is indicated to the next cluster
                if (app.Set(cho)<max(app.Set) & button==1)
                    app.Set(cho)=idx3(cho)+1;
                elseif button==1
                    app.Set(cho)=1;
                end
                Stereogram(app.dipdir, app.dip, app.nplane, idx3, xp, yp)
            end
        end

        % Value changed function: AnalyzespecificsetCheckBox
        function AnalyzespecificsetCheckBoxValueChanged(app, event)
            app.SpecificAnalysisP21 = app.AnalyzespecificsetCheckBox.Value;
            set(app.SetSpinner,'Enable',app.SpecificAnalysisP21 );
            set(app.P21andNCIpointcloudButton,'Enable',app.SpecificAnalysisP21 == 0);
            set(app.ExportdiscintersectionsCheckBox,'Enable',app.SpecificAnalysisP21 == 0);
        end

        % Value changed function: AnalyzespecifcsetCheckBox_2
        function AnalyzespecifcsetCheckBox_2ValueChanged(app, event)
            app.SpecificAnalysisP32 = app.AnalyzespecifcsetCheckBox_2.Value;
            set(app.SetSpinner_2,'Enable',app.SpecificAnalysisP32);
            set(app.CalculateP32and3DNCIButton,'Enable',app.SpecificAnalysisP32==0);
            set(app.ExportDiscInteresectionsCheckBox,'Enable',app.SpecificAnalysisP32==0);
        end

        % Value changed function: SetSpinner_2
        function SetSpinner_2ValueChanged(app, event)
            app.AnalyzedSetP32 = app.SetSpinner_2.Value;
            
        end

        % Value changed function: SetSpinner
        function SetSpinnerValueChanged(app, event)
            app.AnalyzedSetP21 = app.SetSpinner.Value;
            
        end

        % Callback function
        function SetSpinnerValueChanging(app, event)
            changingValue = event.Value;
            
        end

        % Button pushed function: P21andNCIpointcloudButton
        function P21andNCIpointcloudButtonPushed(app, event)
            disp('Welcome to the P21 and NCI automatic calculator')
            disp('It can be used only when analyzing the entire discontinuity network')
            disp('It can take a while for the calculation')
            disp('depending by the pointcloud size and the fracture dataset.')
            disp('If it takes too much time, consider to stop the calculation')
            disp('and use a lower resolution point cloud.')
            tic
            
            % Load in the local memory some variable to perform the parfor
            % loop
            
            PC=app.PC;
            nPC=app.nPC;
            ptCloud=pointCloud(PC(:,1:3));
            CWr=app.CWr;
            xyz=app.xyz;
            Nxyz=app.Nxyz;
            radius=app.radius;
            nplane=length(radius);
            
            % Calculating the intersections between discontinuities
            %This part of code is based on ROKA algorithm (Menegoni et al., 2021)
            tic
            disp(['Starting to calculate all the disconinuities intersections'])
            [Int_data]=intersectionCalculator(app.pathname,app.Fracdata,app.Fracset,app.export_AllIntersection);%matrix contating all the information about the disconinuity intersections
            disp(['End of the calculation of the disconinuities intersections at ', num2str(toc/60),' min.'])
            
            
            %Calculating the best Knn points number representing the scan
            %radius
            tic
            reqKnn=requiredKNNcalc(PC(:,1:3),ptCloud,CWr);
            disp(['Time to calculate the best Knn points number ', num2str(toc/60), ' min.'])
            tic
            
            P21=zeros(length(app.PC(:,1)),1);% record the p21
            P20=zeros(length(app.PC(:,1)),1);% record the p20
            I20=zeros(length(app.PC(:,1)),1);% record the p21
            NCI=zeros(length(app.PC(:,1)),1);% record the NCI (Network Connectivity Index (Elmo et al., 2020; Ref: Elmo D, Stead D, Yang B (2020) Disrupting the concept of rock bridges. In: Proceedings of: 52nd Int. Symposium on Rock Mechanics, Golden, Colorado, Paper #2063)
            MTLcut=zeros(length(app.PC(:,1)),4);% record the statistic of the trace length only inside the circularwindow(mean, st.dev., min, max)
            MTLuncut=zeros(length(app.PC(:,1)),4);% record the statistic of the trace length of the fracture that intersect the window (mean, st.dev., min, max)
            num_idxK=zeros(length(app.PC(:,1)),1);%record the number of K nearest neighbour
            
            parfor ptloop= 1: nPC %define a loop foe every point of the cloud
                [idxK, ptdist] = findNearestNeighbors(ptCloud,PC(ptloop,1:3),4*reqKnn);%calculate the index of the Knn points.
                % the idxK indicates the position of the Knn points inside the PC matrix;
                % the ptdist indicates the distance of the points from the 'ptloop' one
                idxK(ptdist>CWr)=[];
                ptdist(ptdist>CWr)=[];
                SPpoints = PC(idxK,1:3);%define the points belonging to the scan area (based on Knn algorithm)
                num_idxK(ptloop)=length(idxK(:,1));
                %Calculating the center, normals, radius and attitude (dipdir and dip) of the scanplane
                SP_center = mean(PC(idxK,1:3));%scanplane center
                if length(idxK)>3 % if the analyzed point has three or more nearest neighbour points closer that fall in the scan radius, the code perfomr the consequent analysis
                    % This if loop is necessary becasue sometimes, sparse points could
                    % be present and they have a distance from the other point of the
                    % pointcloud longer than the defined scan radius. Therefore, it is
                    % necessary to define exclude these points from calculation
                    %scanplane normals
                    [V, D] = eig(cov(PC(idxK,1:3))); % calculate eigenvector 'V' and eigenvalue 'D' of thecovariance matrix 'C'
                    SP_N = V(:,1)';
                    SP_N = SP_N/norm(SP_N);%RECENTLY ADDED
                    SPr(ptloop,1) = max(ptdist);%radius equal to the most distance filtered Knn points (always lower than userSP)
                    [sdipdir,sdip]= normal2attitude(SP_N);%attitude of the scanplane
                    slopeDipdirDip(ptloop,:)=[sdipdir,sdip];
                    %         if SP_N (1,3)<0
                    %             overhanging(ptloop,1)=1;
                    %         else
                    %             overhanging(ptloop,1)=0;
                    %         end
                    %calculating intersection between discontinties and finite scan plane
                    [p21, p20, i20, nci, muL2D, stdL2D, maxL2D,minL2D,muL3D, stdL3D, maxL3D,minL3D] = NCIcalc(SP_center, SP_N, SPr(ptloop,1), xyz, Nxyz, radius, Int_data);
                    P21(ptloop)=p21;
                    P20(ptloop)=p20;
                    I20(ptloop)=i20;
                    NCI(ptloop)=nci;
                    MTLcut(ptloop,:)=[muL2D, stdL2D, maxL2D,minL2D];%mean, st.dev., max and min values of the 'cutted'trace length (only the segment within the circular window)
                    MTLuncut(ptloop,:)= [muL3D, stdL3D, maxL3D,minL3D];%mean, st.dev., max and min values of dimension of the fractures that intersect the circular window
                end
            end
            disp(['->Exporting_results_at_= ',num2str(toc/60,'%.1f'), '(min)'])
            mkdir (app.pathname,'P21_NCI_pointclouds_resuls')
            clear fid
            fid = fopen(fullfile([app.pathname,'P21_NCI_pointclouds_resuls'],['P21_r_',num2str(app.CWr,'%.1f'),'.txt']),'wt');
            fprintf(fid, 'X Y Z R G B P21 P20 I20 NCI MTL2D TL2Dstd TL2Dmax TL2Dmin MFL3D FL3Dstd FL3Dmax FL3Dmin DipDir Dip Scanradius \n');
            fprintf(fid, '%.6f %.6f %.6f %3.0f %3.0f %3.0f %.3f %.3f %.3f %.3f %.3f %.3f %.3f %.3f %.3f %.3f %.3f %.3f %.3f %.3f %.3f \n', ...
                [PC(:,1),PC(:,2),PC(:,3),...
                PC(:,4),PC(:,5),PC(:,6),P21(:), P20, I20, NCI...
                MTLcut(:,1),MTLcut(:,2),MTLcut(:,3),MTLcut(:,4),...
                MTLuncut(:,1),MTLuncut(:,2),MTLuncut(:,3),MTLuncut(:,4)...
                slopeDipdirDip(:,1),slopeDipdirDip(:,2), SPr(:)]');
            fclose(fid);
            disp('End of point cloud exporting process')
        end

        % Value changed function: ExportdiscintersectionsCheckBox
        function ExportdiscintersectionsCheckBoxValueChanged(app, event)
            app.export_AllIntersection = app.ExportdiscintersectionsCheckBox.Value;
            
        end

        % Button pushed function: CalculateP32and3DNCIButton
        function CalculateP32and3DNCIButtonPushed(app, event)
            disp('Welcome from the P32 calculator')
            tic
            %Load all the app object to get aviable the parfor
            if app.SpecificAnalysisP32 == 0
                xyz=app.xyz;
                Nxyz=app.Nxyz;
                radius=app.radius;
                nplane=app.n;
            else
                xyz=app.xyz(app.Set==app.AnalyzedSetP32,:);
                Nxyz=app.Nxyz(app.Set==app.AnalyzedSetP32,:);
                radius=app.radius(app.Set==app.AnalyzedSetP32,:);
                nplane=sum(app.Set==app.AnalyzedSetP32);
            end
            pn=app.pathname;
            PC=app.PC;
            nPC=app.nPC;
            SVr=app.p32scanradius;
            
            %% Calculating the intersections between discontinuities
            %This part of code is based on ROKA algorithm (Menegoni et al., 2021)
            tic
            disp(['Starting to calculate all the disconinuities intersections'])
            [Int_data]=intersectionCalculator(app.pathname,app.Fracdata,app.Fracset,app.export_AllIntersection);%matrix contating all the information about the disconinuity intersections
            disp(['End of the calculation of the disconinuities intersections at ', num2str(toc/60),' min.'])
            
            %% LOAD & READ the Pointcloud file
            usePC=1; %if UsePC=1 the code use the pointcloud, if = 0 it doesn't use the PC
            if usePC==0
                SVxyz=[1.295830,1.103351,1.608449];% define scan volume (sphere) center
                PC=SVxyz;
            end
            %%P32 calculation
            %1) calculate intersection between the scan volume and the fracture
            %'infinite plane'
            sumarea=0;
            sumfrac=0;
            sumint=0;
            sumintlength=0;
            sumfraccenter=0;
            sumvolumelinear=0;
            sumvolumeroot= 0;
            P33linear=zeros(length(PC),1);
            P33root=zeros(length(PC),1);
            MeanAperturelinear=zeros(length(PC),1);
            MeanApertureroot=zeros(length(PC),1);
            P32=zeros(length(PC),1);
            P30=zeros(length(PC),1);
            P30center=zeros(length(PC),1);
            I30=zeros(length(PC),1);
            I31=zeros(length(PC),1);
            mkdir (pn, 'P32_NCI3D_P33_pointcloud_results')
            disp('DATASET_STATISTIC')
            disp(['Pointcloud_= ', num2str(length(PC(:,1))),' (points)'])
            disp(['Fracture_numbers_= ', num2str(nplane)])
            % CALCULATE P32
            disp(['START_calculation_P32_NCI3D_for_scan radius_= ',num2str(SVr,'%.2f'),' (m)'])
            %             D = parallel.pool.DataQueue;%Set a DataQueue for the watbar
            %             h = waitbar(0, 'Please wait ...');%Create the wait bar
            %             afterEach(D, @nUpdateWaitbar);%define the updating parameter
            %
            %             N = length(PC(:,1));
            %             p = 1;
            %
            
            parfor jloop = 1: nPC
                %disp(['analyzed point = ', num2str(jloop)])
                SVxyz= PC(jloop,1:3);
                SV=[SVxyz,SVr];% scan volume express as required yby the function intersectPlaneSphere
                sumarea=0;
                sumfrac=0;
                sumint=0;
                sumintlength=0;
                sumfraccenter=0;
                sumvolumelinear = 0;
                sumvolumeroot = 0;
                sumAperturelinear = 0;
                sumApertureroot = 0;
                
                for iloop = 1 : numel(Int_data.x1(:))% For each interssections of discontinuities
                    
                    ptsAB = intLineSphere([Int_data.x1(iloop),Int_data.y1(iloop),Int_data.z1(iloop)],...
                        [Int_data.x2(iloop),Int_data.y2(iloop), Int_data.z2(iloop)], SV);% calculation of the intersection points between the sphere and the infinite line of intersection
                    
                    if sum(sum(isnan(ptsAB)))==0
                        dtemp=dist2points(ptsAB(1,:),ptsAB(2,:))
                        sumint=sumint+1;
                        sumintlength=sumintlength+dtemp;
                    end
                    
                end
                
                for iloop = 1 : nplane %for each fracture plane
                    %disp(['analyzed disc = ', num2str(iloop)])
                    
                    scanCIRCtemp =zeros(1,4);
                    PLANEtemp = [xyz(iloop,:), Nxyz(iloop,:)];
                    dPlCi = dist2points(xyz(iloop,:), SVxyz); %distance between the centers of the plane and the sphere
                    %dPlCi = sqrt(d(1)^2 + d(2)^2 + d(3)^2) where d(1),
                    %d(2) and (3) are the distances along x, y and z,
                    %respectively.
                    if dPlCi < SVr % if the distance between fracture and scansphere centers is lower than scansphere radius, count the fracture center as inside the scansphere.
                        sumfraccenter=sumfraccenter+1;
                    end

                    dOrSp= (-dot (Nxyz(iloop,:), PC(jloop,1:3))); %distance from origin-sphere centertoward normal vector of the plane
                    dOrPl= (-dot (Nxyz(iloop,:), xyz(iloop,:))); %distance from origin-plane centertoward normal vector of the plane
                    aSpPl=abs(dOrSp - dOrPl); %distance from sphere-plane centers toward normal vector of the plane
                     
                    
                    if aSpPl < SVr % && dPlCi<(SVr+radius(i))
                        scanCIRCtemp = intPlaneSphere(PLANEtemp, SV);
                        IntArea = intCirc2Circ(scanCIRCtemp(1:3),scanCIRCtemp(4), xyz(iloop,:), radius(iloop));
                        Aperturelinear = 0.001169*radius(iloop)*2;%aperture as expressed by the linear eq. in MOVE
                        Apertureroot = 0.001095*sqrt(radius(iloop)*2);%aperture as expressed by the root eq. in MOVE
                        IntVolumelinear = IntArea * Aperturelinear;%area multiplied by linear-aperture
                        IntVolumeroot = IntArea * Apertureroot;%area multiplied by root-aperture
                        sumfrac=sumfrac+1;
                    else
                        IntArea = 0;
                        IntVolumelinear = 0;
                        IntVolumeroot = 0;
                        Aperturelinear = 0;
                        Apertureroot = 0;
                    end


                    sumarea = sumarea + IntArea;
                    sumvolumelinear = sumvolumelinear + IntVolumelinear;
                    sumvolumeroot= sumvolumeroot + IntVolumeroot;
                    sumAperturelinear = sumAperturelinear + Aperturelinear;
                    sumApertureroot = sumApertureroot + Apertureroot;

                end
                SVvol = ((4/3)*(3.14159265359)*(SVr^3));
                P33linear (jloop,1) = sumvolumelinear / SVvol; 
                P33root (jloop,1) = sumvolumeroot / SVvol;
                if sumfrac >0
                MeanAperturelinear (jloop, 1) = sumAperturelinear/sumfrac;
                MeanApertureroot (jloop, 1) = sumApertureroot/sumfrac;
                else 
                MeanAperturelinear (jloop, 1) = 0;
                MeanApertureroot (jloop, 1) = 0;
                end
                P32 (jloop,1) = sumarea / SVvol; %total P32 (all sets together)
                P30 (jloop,1) = sumfrac / SVvol; %total P30 (takes into account all the fractures that intersect the scan sphere
                P30center(jloop,1)=sumfraccenter / SVvol; %takes into account only the fractures that have their center inside the scan sphere
                I30 (jloop,1) =  sumint / SVvol;
                I31 (jloop,1) =  sumintlength / SVvol;
                NCI3D(jloop,1)=(P32(jloop,1)/P30(jloop,1))*(I31(jloop,1)/I30(jloop,1));
                %                 send(D, jloop);
            end
            
            disp(['->Exporting_results_at_= ',num2str(toc/60,'%.1f'), '(min)'])
            clear fid
            fid = fopen(fullfile([pn,'P32_NCI3D_P33_pointcloud_results'],['P32_NCI_P33r_',num2str(SVr,'%.2f'),'.txt']),'wt');
            if numel(PC(1,:))==6
            fprintf(fid, 'X Y Z R G B P32 P30 P30center I30 I31 NCI3D P33linear P33root meanAperturelinear meanApertureroot \n');
            fprintf(fid, '%.6f %.6f %.6f %3.0f %3.0f %3.0f %.3f %.3f %.3f %.3f %.3f %.3f %.3f %.3f %.5f %.5f \n', [PC(:,1),PC(:,2),PC(:,3),PC(:,4),PC(:,5),PC(:,6),P32(:),P30(:),P30center(:),I30(:),I31(:),NCI3D(:), P33linear(:), P33root(:),MeanAperturelinear(:),MeanApertureroot(:)]');
            fclose(fid);
            end
            if numel(PC(1,:))==3
            fprintf(fid, 'X Y Z P32 P30 P30center I30 I31 NCI3D P33linear P33root meanAperturelinear meanApertureroot \n');
            fprintf(fid, '%.6f %.6f %.6f %.3f %.3f %.3f %.3f %.3f %.3f %.5f %.5f %.6f %.6f \n', [PC(:,1),PC(:,2),PC(:,3),P32(:),P30(:),P30center(:),I30(:),I31(:),NCI3D(:), P33linear(:), P33root(:), MeanAperturelinear(:),MeanApertureroot(:)]');
            fclose(fid);
            end
            disp('End of point cloud exporting process')
            %             function nUpdateWaitbar(~)
            %                 waitbar(p/N, h);
            %                 p = p + 1;
            %             end
        end

        % Value changed function: ExportDiscInteresectionsCheckBox
        function ExportDiscInteresectionsCheckBoxValueChanged(app, event)
            value = app.ExportDiscInteresectionsCheckBox.Value;
            
        end

        % Button pushed function: ImportpicksandsetButton
        function ImportpicksandsetButtonPushed(app, event)
            [app.filename, app.pathname] = uigetfile({'*.xlsx', 'Select a XLSX file'},'mytitle',app.pathname);
            app.Pickdata=readtable(fullfile(app.pathname, app.filename));
            app.n=numel(app.Pickdata.Dip); %number of rows (= number of plane)
            %% DEFINE VARIABLES FOR CALCULATION
            app.dipdir =  app.Pickdata.Azimuth(2:end);%Dip direction
            app.dip =  app.Pickdata.Dip(2:end);%Dip angle
            app.depth = app.pickdata.Depth(2:end);

        end

        % Button pushed function: ImportmeansetorientationButton
        function ImportmeansetorientationButtonPushed(app, event)
            [app.filenameset, app.pathnameset] = uigetfile({'*.xlsx', 'Select a XLSX file'},'mytitle',app.pathname);
            app.Pickset=readtable(fullfile(app.pathnameset, app.filenameset));
            app.n=numel(app.Pickset.Set); %number of rows (= number of plane)
            
            %% DEFINE VARIABLES FOR CALCULATION
            app.Set = app.Pickdata.Set;
            app.Set(isnan(app.Set)) = max(app.Set) + 1;%Change the NaN value (used for the random/not defined set) with a new numbering
            app.nplane=app.n;
            
        end

        % Value changed function: P10calculationdepthrangeSpinner
        function P10calculationdepthrangeSpinnerValueChanged(app, event)
            P10depthrange = app.P10calculationdepthrangeSpinner.Value;
            app.step=P10depthrange;
            app.buffer=P10depthrange;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.P10ScanlineTab = uitab(app.TabGroup);
            app.P10ScanlineTab.Title = 'P10: Scanline';
            app.LoadScanlineButton = uibutton(app.P10ScanlineTab, 'push');
            app.LoadScanlineButton.ButtonPushedFcn = createCallbackFcn(app, @LoadScanlineButtonPushed, true);
            app.LoadScanlineButton.Text = 'Load Scanline';
            app.CalculateDiscontinuityinformationButton = uibutton(app.P10ScanlineTab, 'push');
            app.CalculateDiscontinuityinformationButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateDiscontinuityinformationButtonPushed, true);
            app.CalculateDiscontinuityinformationButton.Text = 'Calculate Discontinuity information';
            app.ExportDiscontinuityInformationButton = uibutton(app.P10ScanlineTab, 'push');
            app.ExportDiscontinuityInformationButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDiscontinuityInformationButtonPushed, true);
            app.ExportDiscontinuityInformationButton.Text = 'Export Discontinuity Information';
            app.P10TooldescriptionTextAreaLabel = uilabel(app.P10ScanlineTab);
            app.P10TooldescriptionTextAreaLabel.Text = 'P10 Tool description';
            app.P10TooldescriptionTextArea = uitextarea(app.P10ScanlineTab);
            app.P10TooldescriptionTextArea.Value = {'This tool calculate the P10 for the imported scanlines. Workflow:'; ''; '1) Load/import the scanlines directly from the storage folder (they muist be saved as dxf file);'; ''; '2) start the calculation of the discontinuity information;'; ''; '3) Export the Discontinuity Information'; ''; 'In the scnaline folder, a XLSX file with all the output information will be created.'};
            app.P10ImagelogTab = uitab(app.TabGroup);
            app.P10ImagelogTab.Title = 'P10: Image log';
            app.ImportpicksandsetButton = uibutton(app.P10ImagelogTab, 'push');
            app.ImportpicksandsetButton.ButtonPushedFcn = createCallbackFcn(app, @ImportpicksandsetButtonPushed, true);
            app.ImportpicksandsetButton.Text = '1) Import picks and set';
            app.ImportmeansetorientationButton = uibutton(app.P10ImagelogTab, 'push');
            app.ImportmeansetorientationButton.ButtonPushedFcn = createCallbackFcn(app, @ImportmeansetorientationButtonPushed, true);
            app.ImportmeansetorientationButton.Text = '2) Import mean set orientation';
            app.WellboredeviationLabel = uilabel(app.P10ImagelogTab);
            app.WellboredeviationLabel.Text = '3) Wellbore deviation';
            app.WellboredeviationSpinner = uispinner(app.P10ImagelogTab);
            app.WellboredeviationAzimuthLabel = uilabel(app.P10ImagelogTab);
            app.WellboredeviationAzimuthLabel.Text = '4) Wellbore deviation Azimuth';
            app.WellboredeviationAzimuthSpinner = uispinner(app.P10ImagelogTab);
            app.P10calculationdepthrangeLabel = uilabel(app.P10ImagelogTab);
            app.P10calculationdepthrangeLabel.Text = '5) P10 calculation  depth range';
            app.P10calculationdepthrangeSpinner = uispinner(app.P10ImagelogTab);
            app.P10calculationdepthrangeSpinner.ValueChangedFcn = createCallbackFcn(app, @P10calculationdepthrangeSpinnerValueChanged, true);
            app.P10calculationdepthrangeSpinner.Value = 1;
            app.CalculateP10Button = uibutton(app.P10ImagelogTab, 'push');
            app.CalculateP10Button.Text = 'Calculate P10';
            app.P21CircularscanwindowTab = uitab(app.TabGroup);
            app.P21CircularscanwindowTab.Title = 'P21: Circular scan window';
            app.SelectthecentrerofCWButton = uibutton(app.P21CircularscanwindowTab, 'push');
            app.SelectthecentrerofCWButton.ButtonPushedFcn = createCallbackFcn(app, @SelectthecentrerofCWButtonPushed, true);
            app.SelectthecentrerofCWButton.Text = 'Select the centrer of CW';
            app.CWradiusSpinnerLabel = uilabel(app.P21CircularscanwindowTab);
            app.CWradiusSpinnerLabel.Text = 'CW radius';
            app.CWradiusSpinner = uispinner(app.P21CircularscanwindowTab);
            app.CWradiusSpinner.Step = 0.1;
            app.CWradiusSpinner.ValueChangedFcn = createCallbackFcn(app, @CWradiusSpinnerValueChanged, true);
            app.CWradiusSpinner.Value = 1;
            app.DefineCWradiusbelowLabel = uilabel(app.P21CircularscanwindowTab);
            app.DefineCWradiusbelowLabel.Text = 'Define CW radius below';
            app.ExportparametersButton = uibutton(app.P21CircularscanwindowTab, 'push');
            app.ExportparametersButton.ButtonPushedFcn = createCallbackFcn(app, @ExportparametersButtonPushed, true);
            app.ExportparametersButton.Text = 'Export parameters';
            app.P21TooldescriptionTextAreaLabel = uilabel(app.P21CircularscanwindowTab);
            app.P21TooldescriptionTextAreaLabel.Text = 'P21Tool description';
            app.P21TooldescriptionTextArea = uitextarea(app.P21CircularscanwindowTab);
            app.P21TooldescriptionTextArea.Value = {'This tool calculate the P21 using the disconinuity geometry and sets and the point cloud. Workflow:'; ''; '1) select the a point of the input pointcloud, that will defined the position of the center of the Ciruclar Window'; ''; '2) Sample the Circualr Window'; ''; '3) Export all the output results'; ''; 'The tool perform the analysis in a buffer of ciruclar window'; ''};
            app.P21pointcloudButton = uibutton(app.P21CircularscanwindowTab, 'push');
            app.P21pointcloudButton.ButtonPushedFcn = createCallbackFcn(app, @P21pointcloudButtonPushed, true);
            app.P21pointcloudButton.Text = 'P21 point cloud';
            app.ManualsamplingLabel = uilabel(app.P21CircularscanwindowTab);
            app.ManualsamplingLabel.Text = 'Manual sampling';
            app.AutomaticsamplingLabel = uilabel(app.P21CircularscanwindowTab);
            app.AutomaticsamplingLabel.Text = 'Automatic sampling';
            app.AnalyzespecificsetCheckBox = uicheckbox(app.P21CircularscanwindowTab);
            app.AnalyzespecificsetCheckBox.ValueChangedFcn = createCallbackFcn(app, @AnalyzespecificsetCheckBoxValueChanged, true);
            app.AnalyzespecificsetCheckBox.Text = 'Analyze specific set';
            app.AnalyzespecificsetCheckBox.Value = true;
            app.SetSpinnerLabel = uilabel(app.P21CircularscanwindowTab);
            app.SetSpinnerLabel.Text = 'Set';
            app.SetSpinner = uispinner(app.P21CircularscanwindowTab);
            app.SetSpinner.ValueChangedFcn = createCallbackFcn(app, @SetSpinnerValueChanged, true);
            app.SetSpinner.Tooltip = {'Insert the set number. Random set is Nset + 1'};
            app.SetSpinner.Value = 1;
            app.P21andNCIpointcloudButton = uibutton(app.P21CircularscanwindowTab, 'push');
            app.P21andNCIpointcloudButton.ButtonPushedFcn = createCallbackFcn(app, @P21andNCIpointcloudButtonPushed, true);
            app.P21andNCIpointcloudButton.Enable = 'off';
            app.P21andNCIpointcloudButton.Text = 'P21 and NCI pointcloud';
            app.ExportdiscintersectionsCheckBox = uicheckbox(app.P21CircularscanwindowTab);
            app.ExportdiscintersectionsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ExportdiscintersectionsCheckBoxValueChanged, true);
            app.ExportdiscintersectionsCheckBox.Enable = 'off';
            app.ExportdiscintersectionsCheckBox.Text = 'Export disc. intersections';
            app.ExportdiscintersectionsCheckBox.Value = true;
            app.P32ScanvolumeTab = uitab(app.TabGroup);
            app.P32ScanvolumeTab.Title = 'P32: Scan volume';
            app.CalculateP32Button = uibutton(app.P32ScanvolumeTab, 'push');
            app.CalculateP32Button.ButtonPushedFcn = createCallbackFcn(app, @CalculateP32ButtonPushed, true);
            app.CalculateP32Button.Text = 'Calculate P32';
            app.DefinethevalueofthescanradiusthatyouwanttouseLabel = uilabel(app.P32ScanvolumeTab);
            app.DefinethevalueofthescanradiusthatyouwanttouseLabel.Text = 'Define the value of the scan radius that you want to use';
            app.DefinetherangeofthescanradiusthatyouwanttotestLabel_2 = uilabel(app.P32ScanvolumeTab);
            app.DefinetherangeofthescanradiusthatyouwanttotestLabel_2.Text = 'Define the range of the scan radius that you want to test';
            app.P32TooldescriptionTextAreaLabel = uilabel(app.P32ScanvolumeTab);
            app.P32TooldescriptionTextAreaLabel.Text = 'P32 Tool description';
            app.P32TooldescriptionTextArea = uitextarea(app.P32ScanvolumeTab);
            app.P32TooldescriptionTextArea.Value = {'This tool calculate the P32 for each point of the input point cloud using a moving scan sphere. Workflow:'; ''; '1) define the radius of the scan volume;'; ''; '2) start the P32 calculation'; ''; '3) Be patient! The P32 calculation will take a while.'; 'The amount times depends by: computer performance;   number of point of the pointcloud; number of disconinuities.'};
            app.scanradiusSpinnerLabel = uilabel(app.P32ScanvolumeTab);
            app.scanradiusSpinnerLabel.Text = 'scan radius';
            app.scanradiusSpinner = uispinner(app.P32ScanvolumeTab);
            app.scanradiusSpinner.Step = 0.1;
            app.scanradiusSpinner.ValueChangedFcn = createCallbackFcn(app, @scanradiusSpinnerValueChanged, true);
            app.scanradiusSpinner.Value = 1;
            app.AnalyzespecifcsetCheckBox_2 = uicheckbox(app.P32ScanvolumeTab);
            app.AnalyzespecifcsetCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @AnalyzespecifcsetCheckBox_2ValueChanged, true);
            app.AnalyzespecifcsetCheckBox_2.Text = 'Analyze specifc set';
            app.AnalyzespecifcsetCheckBox_2.Value = true;
            app.SetSpinner_2Label = uilabel(app.P32ScanvolumeTab);
            app.SetSpinner_2Label.Text = 'Set';
            app.SetSpinner_2 = uispinner(app.P32ScanvolumeTab);
            app.SetSpinner_2.ValueChangedFcn = createCallbackFcn(app, @SetSpinner_2ValueChanged, true);
            app.SetSpinner_2.Value = 1;
            app.CalculateP32and3DNCIButton = uibutton(app.P32ScanvolumeTab, 'push');
            app.CalculateP32and3DNCIButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateP32and3DNCIButtonPushed, true);
            app.CalculateP32and3DNCIButton.Enable = 'off';
            app.CalculateP32and3DNCIButton.Text = 'Calculate P32 and 3D NCI';
            app.ExportDiscInteresectionsCheckBox = uicheckbox(app.P32ScanvolumeTab);
            app.ExportDiscInteresectionsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ExportDiscInteresectionsCheckBoxValueChanged, true);
            app.ExportDiscInteresectionsCheckBox.Enable = 'off';
            app.ExportDiscInteresectionsCheckBox.Text = 'Export Disc. Interesections';
            app.ExportDiscInteresectionsCheckBox.Value = true;
            app.DiscontinuityIntensityCalculatorandEstimatorDICELabel = uilabel(app.UIFigure);
            app.DiscontinuityIntensityCalculatorandEstimatorDICELabel.Text = 'Discontinuity Intensity Calculator and Estimator (DICE)';
            app.ImportDiscontinuity3DdataButton = uibutton(app.UIFigure, 'push');
            app.ImportDiscontinuity3DdataButton.ButtonPushedFcn = createCallbackFcn(app, @ImportDiscontinuity3DdataButtonPushed, true);
            app.ImportDiscontinuity3DdataButton.Text = 'Import Discontinuity 3D data';
            app.ImportDiscontinuitysetsdataButton = uibutton(app.UIFigure, 'push');
            app.ImportDiscontinuitysetsdataButton.ButtonPushedFcn = createCallbackFcn(app, @ImportDiscontinuitysetsdataButtonPushed, true);
            app.ImportDiscontinuitysetsdataButton.Text = 'Import Discontinuity sets data';
            app.ImportPointcloudButton = uibutton(app.UIFigure, 'push');
            app.ImportPointcloudButton.ButtonPushedFcn = createCallbackFcn(app, @ImportPointcloudButtonPushed, true);
            app.ImportPointcloudButton.Text = 'Import Pointcloud';
            app.InstructionsTextAreaLabel = uilabel(app.UIFigure);
            app.InstructionsTextAreaLabel.Text = 'Instructions';
            app.InstructionsTextArea = uitextarea(app.UIFigure);
            app.InstructionsTextArea.Value = {'1) Import pointcloud, disconinuities geometry data, discontinuities sets belonging data and the mean orientation of the sets.'; '2) Use one of the tool below '};
            app.ImportDiscontinuitymeanorientButton = uibutton(app.UIFigure, 'push');
            app.ImportDiscontinuitymeanorientButton.ButtonPushedFcn = createCallbackFcn(app, @ImportDiscontinuitymeanorientButtonPushed, true);
            app.ImportDiscontinuitymeanorientButton.Text = 'Import Discontinuity mean orient.';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'LOGO_DICE.jpg';
            app.StereonetplotpolesButton = uibutton(app.UIFigure, 'push');
            app.StereonetplotpolesButton.ButtonPushedFcn = createCallbackFcn(app, @StereonetplotpolesButtonPushed, true);
            app.StereonetplotpolesButton.Text = 'Stereonet - plot poles';
            app.StereonetAdjustsetsworkinprogressButton = uibutton(app.UIFigure, 'push');
            app.StereonetAdjustsetsworkinprogressButton.ButtonPushedFcn = createCallbackFcn(app, @StereonetAdjustsetsworkinprogressButtonPushed, true);
            app.StereonetAdjustsetsworkinprogressButton.Text = 'Stereonet - Adjust sets (workinprogress)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DICE_v_1_1beta

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end